classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Funciones  matlab.ui.control.DropDown
        SealLabel  matlab.ui.control.Label
        UIAxes     matlab.ui.control.UIAxes
    end

    properties (Access = private)
      funcTitles = ["Latidos del corazón", "Ruido", "Electricidad", "Vibración", "Exponencial compleja", "Escalón", "Impulso", "Rampa", "Rectangular", "Triangular"];
    end
    methods (Access = private)
    function addLibToPath(app)
        addpath(fullfile(app.PathToApp, 'lib'));
    end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Funciones
        function FuncionesValueChanged(app, event)
            selectedFunction = app.Funciones.Value;
            indexfunc= strcmp(selectedFunction,app.funcTitles);
            f=join(string(num2str(indexfunc)));
            display(f)
            title(app.UIAxes,"Señal"+" "+"de"+" "+selectedFunction);

            if     f=="1  0  0  0  0  0  0  0  0  0"

            elseif f=="0  1  0  0  0  0  0  0  0  0"

            elseif f=="0  0  1  0  0  0  0  0  0  0"

            elseif f=="0  0  0  1  0  0  0  0  0  0"

            elseif f=="0  0  0  0  1  0  0  0  0  0"

            elseif f=="0  0  0  0  0  1  0  0  0  0"
                
            elseif f=="0  0  0  0  0  0  1  0  0  0"

            elseif f=="0  0  0  0  0  0  0  1  0  0"

            elseif f=="0  0  0  0  0  0  0  0  1  0"

            elseif f=="0  0  0  0  0  0  0  0  0  1"

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Señal';
            app.Funciones = uidropdown(app.UIFigure);
            app.Funciones.Items = {'Latidos del corazón', 'Ruido', 'Electricidad', 'Vibración'};
            app.Funciones.ValueChangedFcn = createCallbackFcn(app, @FuncionesValueChanged, true);
            app.Funciones.Value = 'Latidos del corazón';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end