classdef signal_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Componente               matlab.ui.control.DropDown
        ComponenteLabel          matlab.ui.control.Label
        Representacion           matlab.ui.control.Slider
        TiempoLabel              matlab.ui.control.Label
        FrecuenciaD              matlab.ui.control.Knob
        FrecuenciaKnob_2Label_2  matlab.ui.control.Label
        RepresentacionDrop       matlab.ui.control.DropDown
        RepresentacinLabel       matlab.ui.control.Label
        Ancho                    matlab.ui.control.Knob
        AnchoKnob_2Label         matlab.ui.control.Label
        Frecuencia               matlab.ui.control.Knob
        FrecuenciaKnob_2Label    matlab.ui.control.Label
        Ciclo                    matlab.ui.control.Knob
        CicloKnob_2Label         matlab.ui.control.Label
        Base                     matlab.ui.control.Knob
        BaseLabel                matlab.ui.control.Label
        Amplitud                 matlab.ui.control.Knob
        AmplitudKnob_2Label      matlab.ui.control.Label
        Sign                     matlab.ui.control.DropDown
        SealDropDownLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      funcTitles = ["Cuadrada", "Diente de sierra", "Senoidal", "Exponencial creciente y decreciente", "Exponencial compleja", "Escalón", "Impulso", "Rampa", "Rectangular", "Triangular"];
      time=["Continuo","Discreto"];
    end
    methods (Access = private)
    function addLibToPath(app)
        addpath(fullfile(app.PathToApp, 'lib'));
    end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, as)
            
        end

        % Callback function
        function AmplitudSliderValueChanged(app, event)
            
        end

        % Value changed function: Amplitud, Ancho, Base, Ciclo, 
        % ...and 5 other components
        function SignValueChanged(app, event)
            selectedFunction = app.Sign.Value;
            indexfunc= strcmp(selectedFunction,app.funcTitles);
            f=join(string(num2str(indexfunc)));
            title(app.UIAxes,selectedFunction);
            
            if app.RepresentacionDrop.Value=="Continua"
            if f=="1  0  0  0  0  0  0  0  0  0"
               if (app.Amplitud.Value==0 && app.Ciclo.Value==50 && app.Frecuencia.Value==50) 
                    Fs = 10000; %Frecuencia de Muestreo
                    f = 100; %Frecuencia Fundamental
                    T = 3*(1/f); %Número de Periodos a Graficar
                    A = 1;
                    duty = 50; %Ciclo útil
                    t = 0:1/Fs:T-1/Fs; %Tiempo de Simulación
                    x = A*square(2*pi*f*t,duty); %Generación de Señal
                    plot(t,x),grid on,ylim([-1.1 1.1]) %Grafica
               elseif(app.Amplitud.Value~=0 && app.Ciclo.Value~=0 && app.Frecuencia.Value~=0)
                    A = app.Amplitud.Value; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    f = app.Frecuencia.Value; %Frecuencia Fundamental
                    T = 3*(1/f); %Número de Periodos a Graficar
                    duty = app.Ciclo.Value; %Ciclo útil
                    t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                    x = A*square(2*pi*f*t,duty); %Generación de Señal
                    plot(t,x),grid on,ylim([-1.1 1.1]) %Grafica
               end
%                switch app.Componente.Value
%                    case "Par"
%                        %syms t;
%                       xp = (x + x(end:-1:1))/2;
%                       subplot(2,2,3),plot(t,xp),grid on,title('Parte Par xp(t) de la Señal x(t)'),ylim([-0.1 1.1]) 
%                    case "Impar"
%                        xi = (x - x(end:-1:1))/2;  %Calculo de la parte impar de la señal
%                        subplot(2,2,4),plot(t,xi),grid on ,title('Parte Impar xi(t) de la Señalx(t)'),ylim([-0.1 1.1])
% 
%                    otherwise
%                        z=3;
%                        disp(z)
%                end
xp = (x + x(end:-1:1))/2; %Calculo de la parte par de la señal
xi = (x -x(end:-1:1))/2;  %Calculo de la parte impar de la señal
xr = xp+xi; %Reconstrución de la señal original a partir de la suma de sus partespar e impar
subplot(2,2,1),plot(t,x),grid on , title('Señal Original x(t)' ),ylim([-0.1 1.1])%Grafica señal original
subplot(2,2,2),plot(t,xr),grid on ,title('Señal Reconstruida ,title(x(t)=xp(t)+xi(t)'),ylim([-0.1 1.1]) %Grafica señal reconstruida
subplot(2,2,3),plot(t,xp),grid on,title('Parte Par xp(t) de la Señal x(t)'),ylim([-0.1 1.1]) %Grafica parte par
subplot(2,2,4),plot(t,xi),grid on,title('Parte Impar xi(t) de la Señalx(t)'),ylim([-0.1 1.1]) %Grafica parte impar
          

            elseif f=="0  1  0  0  0  0  0  0  0  0"
                %% Onda Diente de Sierra Tiempo Continuo
                if (app.Amplitud.Value==0 && app.Ancho.Value==0 && app.Frecuencia.Value==50) 
                    A = 1; %Amplitud
                    f = 100; %Frecuencia Fundamental
                    Fs = 10000; %Frecuencia de Muestreo
                    T = 3*(1/f); %Número de Periodos a Graficar
                    width = 0.5; %Ancho
                    t = 0:1/Fs:T-1/Fs; %Tiempo de Simulación
                    x = A*sawtooth(2*pi*f*t,width); %Generación de Señal
                    plot(t,x),grid on,ylim([-1.1 1.1]) %Grafica
                elseif(app.Amplitud.Value~=0 && app.Ancho.Value~=0 && app.Frecuencia.Value~=0)
                    A = app.Amplitud.Value; %Amplitud
                    f = app.Frecuencia.Value; %Frecuencia Fundamental
                    Fs = 10000; %Frecuencia de Muestreo
                    T = 3*(1/f); %Número de Periodos a Graficar
                    width = app.Ancho.Value; %Ancho
                    t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                    x = A*sawtooth(2*pi*f*t,width); %Generación de Señal
                    plot(t,x),grid on,ylim([-1.1 1.1]) %Grafica
                end
xp = (x + x(end:-1:1))/2; %Calculo de la parte par de la señal
xi = (x -x(end:-1:1))/2;  %Calculo de la parte impar de la señal
xr = xp+xi; %Reconstrución de la señal original a partir de la suma de sus partespar e impar
subplot(2,2,1),plot(t,x),grid on , title('Señal Original x(t)' ),ylim([-0.1 1.1])%Grafica señal original
subplot(2,2,2),plot(t,xr),grid on ,title('Señal Reconstruida ,title(x(t)=xp(t)+xi(t)'),ylim([-0.1 1.1]) %Grafica señal reconstruida
subplot(2,2,3),plot(t,xp),grid on,title('Parte Par xp(t) de la Señal x(t)'),ylim([-0.1 1.1]) %Grafica parte par
subplot(2,2,4),plot(t,xi),grid on,title('Parte Impar xi(t) de la Señalx(t)'),ylim([-0.1 1.1]) %Grafica parte impar
          

            elseif f=="0  0  1  0  0  0  0  0  0  0"
               %% Onda Senoidal Tiempo Continuo
                if (app.Amplitud.Value==0 && app.Frecuencia.Value==50) 
                    A = 1; %Amplitud
                    f = 100; %Frecuencia Fundamental
                    phi = pi/2; %Desfase en radianes
                    Fs = 10000; %Frecuencia de Muestreo
                    T = 3*(1/f); %Número de Periodos a Graficar
                    t = 0:1/Fs:T-1/Fs; %Tiempo de Simulación
                    x = A*sin(2*pi*f*t+phi); %Generación de Señal
                    plot(t,x),grid on,ylim([-1.1 1.1]) %Grafica
                elseif(app.Amplitud.Value~=0 && app.Frecuencia.Value~=0)
                    A = app.Amplitud.Value; %Amplitud
                    f = app.Frecuencia.Value; %Frecuencia Fundamental
                    phi = pi/2; %Desfase en radianes
                    Fs = 10000; %Frecuencia de Muestreo
                    T = 3*(1/f); %Número de Periodos a Graficar
                    t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                    x = A*sin(2*pi*f*t+phi); %Generación de Señal
                    plot(t,x),grid on,ylim([-1.1 1.1]) %Grafica
                end

             elseif f=="0  0  0  1  0  0  0  0  0  0"
                %% Onda Exponencial en Tiempo Continuo
                if (app.Base.Value==1) 
                    Fs = 10000; %Frecuencia de Muestreo
                    t = 0:1/Fs:10; %Tiempo de Simulación
                    x1 = exp(t); %Generación de Señal
                    subplot(2,1,1),plot(t,x1),grid on %Grafica
                    r = 1.5;
                    x2 = r.^(-t); %Generación de Señal
                    subplot(2,1,2),plot(t,x2),grid on %Grafica
                elseif app.Base.Value~=1
                    Fs = 10000; %Frecuencia de Muestreo
                    t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                    x1 = exp(t); %Generación de Señal
                    subplot(2,1,1),plot(t,x1),grid on %Grafica
                    r = app.Base.Value;
                    x2 = r.^(-t); %Generación de Señal
                    subplot(2,1,2),plot(t,x2),grid on %Grafica
                end

            elseif f=="0  0  0  0  1  0  0  0  0  0"
                %% Onda Exponencial Compleja
                Fs = 10000; %Frecuencia de Muestreo
                t = -app.Representacion.Value:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                x = exp(1j.*(2.*t+(pi/4))); %Generación de Señal
                xRE = real(x); %Cálculo de la parte Real de x(t)
                xIM = imag(x); %Cálculo de la parte Imaginaria de x(t)
                xMAG = abs(x); %Cálculo de la Magnitud de x(t)
                xARG = atan(imag(x)./real(x)); %Cálculo del Argumento de x(t)
                subplot(2,2,1),plot(t,xRE); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Real de x(t)')
                subplot(2,2,2),plot(t,xIM); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Imaginaria de x(t)')
                subplot(2,2,3),plot(t,xMAG); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Magnitud de x(t)')
                subplot(2,2,4),plot(t,xARG); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Argumento de x(t)')
                hold off;
               
            elseif f=="0  0  0  0  0  1  0  0  0  0"
                %% Señal Escalon en Tiempo Continuo
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                    x1 = A*heaviside(t); %Generación de Señal
                    x2 = A.*ones(1,length(t)); %Generación de Señal
                    x3 = A.*ustep(t); %Generación de Señal ADSP Toolbox
                    subplot(2,2,1),plot(t,x1),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                    subplot(2,2,2),plot(t,x2),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ones')
                    subplot(2,2,3),plot(t,x3),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                    x1 = A*heaviside(t); %Generación de Señal
                    x2 = A.*ones(1,length(t)); %Generación de Señal
                    x3 = A.*ustep(t); %Generación de Señal ADSP Toolbox
                    subplot(2,2,1),plot(t,x1),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                    subplot(2,2,2),plot(t,x2),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ones')
                    subplot(2,2,3),plot(t,x3),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ADSP Toolbox')
                end

            elseif f=="0  0  0  0  0  0  1  0  0  0"
                %% Señal Impulso
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    n = 0:5; %Tiempo de Simulación
                    x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                    %x2 = A.*udelta(n-3); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Impulso con zeros ones')
                    subplot(2,1,2),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Escalón con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    n = 0:5; %Tiempo de Simulación
                    x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                    %%x2 = A.*ustep(n-3); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Impulso con zeros ones')
                    subplot(2,1,2),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Escalón con ADSP Toolbox')
                end
                

            elseif f=="0  0  0  0  0  0  0  1  0  0"
                %% Señal Rampa en Tiempo Continuo
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t1 = 0:1/Fs:5; t2 = 5:1/Fs:10;r = t1;
                    t = [t1,t2]; %Tiempo de Simulación
                    x1 = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                    %x2 = A.*uramp(t-5); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),plot(t,x1),grid on,xlim([0 10]),ylim([0 5]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa ecuación')
%                     subplot(2,1,2),plot(t,x2),grid on,xlim([0 10]),ylim([0 5]) %Grafica
%                     xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t1 = 0:1/Fs:app.Representacion.Value; t2 = app.Representacion.Value:1/Fs:2*app.Representacion.Value;r = t1;
                    t = [t1,t2]; %Tiempo de Simulación
                    x1 = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                    %x2 = A.*uramp(t-5); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),plot(t,x1),grid on,xlim([0 10]),ylim([0 5]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa ecuación')
%                     subplot(2,1,2),plot(t,x2),grid on,xlim([0 10]),ylim([0 5]) %Grafica
%                     xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa con ADSP Toolbox')
                end

            elseif f=="0  0  0  0  0  0  0  0  1  0"
                %% Señal Rectangular en Tiempo Continuo
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t = -1:1/Fs:1; %Tiempo de simulación
                    x = A.*rectpuls(t); %Generación de Señal
                    plot(t,x),grid on,xlim([-1 1]),ylim([0.1 1.1]) %Gráfica %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rectángulo con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t = -1:1/Fs:1; %Tiempo de simulación
                    x = A.*rectpuls(t); %Generación de Señal
                    plot(t,x),grid on,xlim([-1 1]),ylim([0.1 1.1]) %Gráfica %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rectángulo con ADSP Toolbox')
                end

            elseif f=="0  0  0  0  0  0  0  0  0  1"
                %% Onda Triangular Tiempo Continuo
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t = -2:1/Fs:2; %Tiempo de Simulación
                    x = A*tripuls(t); %Generación de Señal
                    plot(t,x),grid on,ylim([-0.1 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Triángulo con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    Fs = 10000; %Frecuencia de Muestreo
                    t = -2:1/Fs:2; %Tiempo de Simulación
                    x = A*tripuls(t); %Generación de Señal
                    plot(t,x),grid on,ylim([-0.1 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Triángulo con ADSP Toolbox')
                end
            end

            else
                if f=="1  0  0  0  0  0  0  0  0  0"
                %% Onda Cuadrada Tiempo Discreto
                if (app.Amplitud.Value==0 && app.Ciclo.Value==50 && app.FrecuenciaD.Value==0)
                    A = 1; %Amplitud
                    f = 0.125; %Frecuencia Fundamental
                    T = 3*(1/f); %Número de Periodos a Graficar
                    duty = 50; %Ciclo útil
                    n = 0:T; %Tiempo de Simulación
                    x = A*square(2*pi*f*n,duty); %Generación de Señal
                    stem(n,x),grid on,ylim([-1.1 1.1]) %Grafica
                elseif(app.Amplitud.Value~=0 && app.Ciclo.Value~=0 && app.FrecuenciaD.Value~=0)
                    A = app.Amplitud.Value; %Amplitud
                    f = app.FrecuenciaD.Value; %Frecuencia Fundamental
                    T = app.Representacion.Value*(1/f); %Número de Periodos a Graficar
                    duty = app.Ciclo.Value; %Ciclo útil
                    n = 0:T; %Tiempo de Simulación
                    x = A*square(2*pi*f*n,duty); %Generación de Señal
                    stem(n,x),grid on,ylim([-1.1 1.1]) %Grafica
                end

            elseif f=="0  1  0  0  0  0  0  0  0  0"
                %% Onda Diente de Sierra Tiempo Discreto
                if (app.Amplitud.Value==0 && app.Ancho.Value==0 && app.FrecuenciaD.Value==0) 
                    A = 1; %Amplitud
                    f = 0.125; %Frecuencia Fundamental
                    T = 3*(1/f); %Número de Periodos a Graficar
                    width = 0.5; %Ancho
                    n = 0:T; %Tiempo de Simulación
                    x = A*sawtooth(2*pi*f*n,width); %Generación de Señal
                    stem(n,x),grid on,ylim([-1.1 1.1]) %Grafica
                elseif(app.Amplitud.Value~=0 && app.Ancho.Value~=0 && app.FrecuenciaD.Value~=0)
                    A = app.Amplitud.Value; %Amplitud
                    f = app.FrecuenciaD.Value; %Frecuencia Fundamental
                    T = app.Representacion.Value*(1/f); %Número de Periodos a Graficar
                    width = app.Ancho.Value; %Ancho
                    n = 0:T; %Tiempo de Simulación
                    x = A*sawtooth(2*pi*f*n,width); %Generación de Señal
                    stem(n,x),grid on,ylim([-1.1 1.1]) %Grafica
                end
            elseif f=="0  0  1  0  0  0  0  0  0  0"
                %% Onda Senoidal Tiempo Discreto
                if (app.Amplitud.Value==0 && app.FrecuenciaD.Value==0) 
                    A = 1; %Amplitud
                    f = 0.125; %Frecuencia Fundamental
                    phi = pi/2; %Desfase en radianes
                    T = 3*(1/f); %Número de Periodos a Graficar
                    n = 0:T; %Tiempo de Simulación
                    x = A*cos(2*pi*f*n-phi); %Generación de Señal
                    stem(n,x),grid on,ylim([-1.1 1.1]) %Grafica
                elseif(app.Amplitud.Value~=0 && app.FrecuenciaD.Value~=0)
                    A = app.Amplitud.Value; %Amplitud
                    f = app.FrecuenciaD.Value; %Frecuencia Fundamental
                    phi = pi/2; %Desfase en radianes
                    T = app.Representacion.Value*(1/f); %Número de Periodos a Graficar
                    n = 0:T; %Tiempo de Simulación
                    x = A*cos(2*pi*f*n-phi); %Generación de Señal
                    stem(n,x),grid on,ylim([-1.1 1.1]) %Grafica
                end

             elseif f=="0  0  0  1  0  0  0  0  0  0"
                %% Onda Exponencial en Tiempo Discreto
                if (app.Base.Value==1)
                    n = 0:10; %Tiempo de Simulación
                    x1 = exp(n); %Generación de Señal
                    subplot(2,1,1),stem(n,x1),grid on %Grafica
                    r = 1.5;
                    x2 = r.^(-n); %Generación de Señal
                    subplot(2,1,2),stem(n,x2),grid on %Grafica
                else
                    n = 0:app.Representacion.Value; %Tiempo de Simulación
                    x1 = exp(n); %Generación de Señal
                    subplot(2,1,1),stem(n,x1),grid on %Grafica
                    r = app.Base.Value;
                    x2 = app.Amplitud.Value.*r.^(-n); %Generación de Señal
                    subplot(2,1,2),stem(n,x2),grid on %Grafica
                end

            elseif f=="0  0  0  0  1  0  0  0  0  0"
                %% Onda Exponencial Compleja en Tiempo Discreto
                n = -app.Representacion.Value:0.1:app.Representacion.Value; %Tiempo de Simulación
                x = exp(1j.*(2.*n+(pi/4))); %Generación de Señal
                xRE = real(x); %Cálculo de la parte Real de x[n]
                xIM = imag(x); %Cálculo de la parte Imaginaria de x[n]
                xMAG = abs(x); %Cálculo de la Magnitud de x[n]
                xARG = atan(imag(x)./real(x)); %Cálculo del Argumento de x[n]
                subplot(2,2,1),stem(n,xRE); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Real de x[n]')
                subplot(2,2,2),stem(n,xIM); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Imaginaria de x[n]')
                subplot(2,2,3),stem(n,xMAG); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Magnitud de x[n]')
                subplot(2,2,4),stem(n,xARG); grid on, ylim([-2 2])%Gráfica
                xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Argumento de x[n]')
               
            elseif f=="0  0  0  0  0  1  0  0  0  0"
                %% Señal Escalon en Tiempo Discreto
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    n = 0:10; %Tiempo de Simulación
                    x1 = A*heaviside(n); %Generación de Señal
                    x2 = A.*ones(1,length(n)); %Generación de Señal
                    x3 = A.*ustep(n); %Generación de Señal ADSP Toolbox
                    subplot(2,2,1),stem(n,x1),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                    subplot(2,2,2),stem(n,x2),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ones')
                    subplot(2,2,3),stem(n,x3),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    n = 0:app.Representacion.Value; %Tiempo de Simulación
                    x1 = A*heaviside(n); %Generación de Señal
                    x2 = A.*ones(1,length(n)); %Generación de Señal
                    x3 = A.*ustep(n); %Generación de Señal ADSP Toolbox
                    subplot(2,2,1),stem(n,x1),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                    subplot(2,2,2),stem(n,x2),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ones')
                    subplot(2,2,3),stem(n,x3),grid on,xlim([-1 10]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ADSP Toolbox')
                end    

            elseif f=="0  0  0  0  0  0  1  0  0  0"
                %% Señal Impulso
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    n = 0:5; %Tiempo de Simulación
                    x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                    %%x2 = A.*dirac(n-3); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Impulso con zeros ones')
                    subplot(2,1,2),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Escalón con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    n = 0:5; %Tiempo de Simulación
                    x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                    %%x2 = A.*dirac(n-3); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Impulso con zeros ones')
                    subplot(2,1,2),stem(n,x1),grid on,xlim([0 5]),ylim([0 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Escalón con ADSP Toolbox')
                end

            elseif f=="0  0  0  0  0  0  0  1  0  0"
                %% Señal Rampa en Tiempo Discreto
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    n1 = 0:5; n2 = 5:10;r = n1;
                    n = [n1,n2]; %Tiempo de Simulación
                    x1 = A.*[zeros(1,length(n1)),r]; %Generación de Señal
                    x2 = A.*uramp(n-5); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),stem(n,x1),grid on,xlim([0 10]),ylim([0 5]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa ecuación')
                    subplot(2,1,2),stem(n,x2),grid on,xlim([0 10]),ylim([0 5]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    n1 = 0:5; n2 = 5:10;r = n1;
                    n = [n1,n2]; %Tiempo de Simulación
                    x1 = A.*[zeros(1,length(n1)),r]; %Generación de Señal
                    x2 = A.*uramp(n-5); %Generación de Señal ADSP Toolbox
                    subplot(2,1,1),stem(n,x1),grid on,xlim([0 10]),ylim([0 5]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa ecuación')
                    subplot(2,1,2),stem(n,x2),grid on,xlim([0 10]),ylim([0 5]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa con ADSP Toolbox') 
                end

            elseif f=="0  0  0  0  0  0  0  0  1  0"
                %% Señal Rectangular en Tiempo Discreto
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    n = -1:0.1:1; %Tiempo de Simulación
                    x = A.*rectpuls(n); %Generación de Señal
                    stem(n,x),grid on,xlim([-1 1]),ylim([0.1 1.1]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rectángulo con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    n = -1:0.1:1; %Tiempo de Simulación
                    x = A.*rectpuls(n); %Generación de Señal
                    stem(n,x),grid on,xlim([-1 1]),ylim([0.1 1.1]) %Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rectángulo con ADSP Toolbox')
                end

            elseif f=="0  0  0  0  0  0  0  0  0  1"
                %% Onda Traingular Tiempo Discreto
                if(app.Amplitud.Value==0)
                    A = 1; %Amplitud
                    n = -2:0.1:2; %Tiempo de Simulación
                    x = A*tripuls(n); %Generación de Señal
                    stem(n,x),grid on,ylim([-0.1 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Triángulo con ADSP Toolbox')
                else
                    A = app.Amplitud.Value; %Amplitud
                    r= -2:0.1:2; %Tiempo de Simulación
                    x = A*tripuls(r); %Generación de Señal
                    stem(r,x),grid on,ylim([-0.1 1.1]) %Grafica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Triángulo con ADSP Toolbox')
                end
                end
            end
            
        end

        % Drop down opening function: Sign
        function SignDropDownOpening(app, event)
            
        end

        % Value changed function: RepresentacionDrop
        function RepresentacionDropValueChanged(app, event)
        
        end

        % Callback function
        function BaseValueChanged(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.Sign = uidropdown(app.UIFigure);
            app.Sign.Items = {'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial creciente y decreciente', 'Exponencial compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Sign.DropDownOpeningFcn = createCallbackFcn(app, @SignDropDownOpening, true);
            app.Sign.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Sign.Value = 'Cuadrada';
            app.AmplitudKnob_2Label = uilabel(app.UIFigure);
            app.AmplitudKnob_2Label.Text = 'Amplitud';
            app.Amplitud = uiknob(app.UIFigure, 'continuous');
            app.Amplitud.Limits = [0 2];
            app.Amplitud.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.Base = uiknob(app.UIFigure, 'continuous');
            app.Base.Limits = [-5 5];
            app.Base.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Base.Value = 1;
            app.CicloKnob_2Label = uilabel(app.UIFigure);
            app.CicloKnob_2Label.Text = 'Ciclo';
            app.Ciclo = uiknob(app.UIFigure, 'continuous');
            app.Ciclo.Limits = [50 100];
            app.Ciclo.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Ciclo.Value = 50;
            app.FrecuenciaKnob_2Label = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label.Text = 'Frecuencia';
            app.Frecuencia = uiknob(app.UIFigure, 'continuous');
            app.Frecuencia.Limits = [0 230];
            app.Frecuencia.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Frecuencia.Value = 50;
            app.AnchoKnob_2Label = uilabel(app.UIFigure);
            app.AnchoKnob_2Label.Text = 'Ancho';
            app.Ancho = uiknob(app.UIFigure, 'continuous');
            app.Ancho.Limits = [0 1];
            app.Ancho.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.RepresentacinLabel = uilabel(app.UIFigure);
            app.RepresentacinLabel.Text = 'Representación';
            app.RepresentacionDrop = uidropdown(app.UIFigure);
            app.RepresentacionDrop.Items = {'Continua', 'Discreta', 'Periodica', 'Aperiodica'};
            app.RepresentacionDrop.ValueChangedFcn = createCallbackFcn(app, @RepresentacionDropValueChanged, true);
            app.RepresentacionDrop.Value = 'Continua';
            app.FrecuenciaKnob_2Label_2 = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label_2.Text = 'FrecuenciaD';
            app.FrecuenciaD = uiknob(app.UIFigure, 'continuous');
            app.FrecuenciaD.Limits = [0 1];
            app.FrecuenciaD.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.TiempoLabel = uilabel(app.UIFigure);
            app.TiempoLabel.Text = 'Tiempo';
            app.Representacion = uislider(app.UIFigure);
            app.Representacion.Limits = [0 20];
            app.Representacion.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Representacion.Value = 10;
            app.ComponenteLabel = uilabel(app.UIFigure);
            app.ComponenteLabel.Text = 'Componente';
            app.Componente = uidropdown(app.UIFigure);
            app.Componente.Items = {'Seleccion', 'Par', 'Impar'};
            app.Componente.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Componente.Value = 'Seleccion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_generator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end